classdef controlApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        StartStopButton             matlab.ui.control.StateButton
        ThrottleKnobLabel           matlab.ui.control.Label
        ThrottleKnob                matlab.ui.control.Knob
        Lamp                        matlab.ui.control.Lamp
        ThrottleSetEditFieldLabel   matlab.ui.control.Label
        ThrottleSetEditField        matlab.ui.control.NumericEditField
        CurrthrottleEditFieldLabel  matlab.ui.control.Label
        CurrthrottleEditField       matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        SerialPortDropDownLabel     matlab.ui.control.Label
        SerialPortDropDown          matlab.ui.control.DropDown
        PlotButton                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        myTimer = timer % Description
        arduinoSerial;% Description
        receivedData = []% Description
    end
    
    properties (Access = private)
        Property4 % Description
        setThrottleValue = 0 % Description
        globalState = 0; % 0 = off   1 = record
        myLine % Description
        numOfBytes = 5;
        bufferLength = 50;  % 1 buffer length = 1 data Set
        bufferBytes;
        receivedArray = [];
        displayLength = 10000;
    end
    
    methods (Access = public)
        
        function TimerCallback(app)
            
            app.Label.Text = datestr(now);
            
            
            myPlot(app);
            if app.arduinoSerial.BytesToOutput == 0
                
                fwrite(app.arduinoSerial,app.setThrottleValue,'async');
                
            end
            
        end
        
        function readData(app)
            % read and scale data from serial port
            received = fread(app.arduinoSerial, app.bufferBytes, 'int8')';
            myNumber = 0;
            for cn = 1:app.bufferLength
                myNumber = cn*app.numOfBytes;
                app.receivedArray(cn,:) = received(1,(myNumber-app.numOfBytes+1):myNumber); 
            end
            app.receivedData = [app.receivedData; app.receivedArray];
            
            app.CurrthrottleEditField.Value = app.receivedData(end,1);
            %             myPlot(app);
            
            
        end
        
        function plotThrottle(app)
            figure;
            plot(app.receivedData(:,1)); 
        end
        
    end
    
    methods (Access = private)
        
        function setThrottle(app,newThrottleValue)
            app.setThrottleValue = newThrottleValue;
            app.ThrottleKnob.Value = newThrottleValue;
            app.ThrottleSetEditField.Value = newThrottleValue;
            
        end
        
        function myPlot(app)
            if isempty(app.receivedData) == 0
                if length(app.receivedData) > app.displayLength
                    %                     plot(app.UIAxes,app.receivedData((end-100):end,1));
                    
                    set(app.myLine,'YData',app.receivedData((end-app.displayLength):end,1));
                else
                    app.myLine = plot(app.UIAxes,app.receivedData(:,1));
                    
                    
                end
            end
        end
        
        function setStandBy(app)
            app.globalState = 0;
            assignin('base','recordData',app.receivedData);
            
            if isempty(app.arduinoSerial) == 0
                stopasync(app.arduinoSerial);
                fclose(app.arduinoSerial);
            end
            stop(app.myTimer);


            %             delete(app.myTimer);
            
            
        end
        
        function setRecord(app)
            app.globalState = 1;
            
            
            instrreset
            
            BAUD = 115200;
            
            %Open Serial Port
            % Windows COM1  - COM6
            % OS X  /dev/cu.usbmodem1D1121
            % Search serial: instrfind  /         portlist=instrhwinfo('serial');
            % List of connected Devices seriallist
            % Reset Serial Ports: instrreset
            
            app.arduinoSerial = serial(app.SerialPortDropDown.Value,'BaudRate',BAUD);


            % call ReadData function if buffer contains uint16 (2 bytes)
            app.arduinoSerial.BytesAvailableFcnCount = app.bufferBytes;
            app.arduinoSerial.BytesAvailableFcnMode = 'byte';
            app.arduinoSerial.BytesAvailableFcn = @(~, ~)app.readData;
            
            %             app.arduinoSerial.OutputBufferSize = 20;
            ylim(app.UIAxes,[0 100]);
            
            fopen(app.arduinoSerial);
            
            start(app.myTimer);
            
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function SetupSystem(app)
            
            app.bufferBytes = app.numOfBytes*app.bufferLength;
            
            app.receivedArray = zeros(app.bufferLength,app.numOfBytes);
            app.myTimer = timer('StartDelay', 0, 'Period', 0.1,'ExecutionMode', 'fixedRate','BusyMode','drop');
            app.myTimer.TimerFcn = @(~, ~) app.TimerCallback;
            
            p = instrhwinfo('serial');
            app.SerialPortDropDown.Items = p.AvailableSerialPorts;
            
            
        end

        % Callback function
        function InputSliderValueChanged(app, event)
            value = app.InputSlider.Value;
            plot(app.UIAxes, value*peaks)
            app.UIAxes.YLim = [-1000 1000];
        end

        % Value changing function: ThrottleKnob
        function ThrottleKnobValueChanging(app, event)
            changingValue = event.Value;
            
            setThrottle(app, changingValue);
            %             fwrite(arduino,changingValue);
            
            
        end

        % Value changed function: ThrottleSetEditField
        function ThrottleSetEditFieldValueChanged(app, event)
            value = app.ThrottleSetEditField.Value;
            setThrottle(app, value)
            %             fwrite(arduino,changingValue);
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            value = app.StartStopButton.Value;
            
            
            if value == 1
                %Running /Start
                
                
                %Receive Data
                setRecord(app);
                
                app.Lamp.Color = 'green';
                
            else
                %Stop
                
                setStandBy(app);
                
                
                app.Lamp.Color = 'red';


            end
            
            
        end

        % Close request function: UIFigure
        function CloseSystem(app, event)
            
            
            %             Serial_Close
            %             pause(0.01);
            
            %             fclose(app.arduinoSerial);
            %             delete(app.arduinoSerial)
            
            setStandBy(app);
            pause(1);
            delete(app.myTimer);
            %
            %             pause(0.01);
            delete(app)


        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            plotThrottle(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseSystem, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Output')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Throttle')
            app.StartStopButton = uibutton(app.UIFigure, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = 'Start/Stop';
            app.ThrottleKnobLabel = uilabel(app.UIFigure);
            app.ThrottleKnobLabel.Text = 'Throttle';
            app.ThrottleKnob = uiknob(app.UIFigure, 'continuous');
            app.ThrottleKnob.ValueChangingFcn = createCallbackFcn(app, @ThrottleKnobValueChanging, true);
            app.Lamp = uilamp(app.UIFigure);
            app.ThrottleSetEditFieldLabel = uilabel(app.UIFigure);
            app.ThrottleSetEditFieldLabel.Text = ' Throttle Set:';
            app.ThrottleSetEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThrottleSetEditField.Limits = [0 100];
            app.ThrottleSetEditField.ValueDisplayFormat = '%.2f';
            app.ThrottleSetEditField.ValueChangedFcn = createCallbackFcn(app, @ThrottleSetEditFieldValueChanged, true);
            app.CurrthrottleEditFieldLabel = uilabel(app.UIFigure);
            app.CurrthrottleEditFieldLabel.Text = 'Curr. throttle';
            app.CurrthrottleEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrthrottleEditField.Limits = [0 100];
            app.CurrthrottleEditField.ValueDisplayFormat = '%.2f';
            app.CurrthrottleEditField.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel = uilabel(app.UIFigure);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.SerialPortDropDown = uidropdown(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = controlApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @SetupSystem)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end